classdef logic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        LogicalOperationsonBinaryImagesLabel  matlab.ui.control.Label
        ORCheckBox          matlab.ui.control.CheckBox
        XORCheckBox         matlab.ui.control.CheckBox
        NOTCheckBox         matlab.ui.control.CheckBox
        ANDCheckBox         matlab.ui.control.CheckBox
        BrowseImage1Button  matlab.ui.control.Button
        BrowseImage2Button  matlab.ui.control.Button
        UIAxes              matlab.ui.control.UIAxes
        UIAxes_2            matlab.ui.control.UIAxes
        UIAxes_3            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseImage1Button
        function image1(app, event)
            global image1
            [filename,filepath] = uigetfile({'*.jpg';'*.PNG'});
            fullName = fullfile(filepath, filename);
            image1 = imread(fullName);
        end

        % Button pushed function: BrowseImage2Button
        function image2(app, event)
            global image2
            [filename,filepath] = uigetfile({'*.jpg';'*.png'});
            fullName = fullfile(filepath, filename);
            image2 = imread(fullName);
        end

        % Value changed function: ANDCheckBox
        function AND(app, event)
            global image1
            global image2
            k = image1 & image2;
            imshow(mat2gray(image1), 'Parent', app.UIAxes);
            imshow(mat2gray(image2), 'Parent', app.UIAxes_2);
            imshow(mat2gray(k), 'Parent', app.UIAxes_3);
 
        end

        % Value changed function: NOTCheckBox
        function not(app, event)
            global image1
            k = ~image1;
            imshow(mat2gray(image1), 'Parent', app.UIAxes);
            imshow(mat2gray(k), 'Parent', app.UIAxes_3);
            
        end

        % Value changed function: XORCheckBox
        function oor(app, event)
            global image1
            global image2
            k =xor( image1, image2);
            imshow(mat2gray(image1), 'Parent', app.UIAxes);
            imshow(mat2gray(image2), 'Parent', app.UIAxes);
            imshow(mat2gray(k), 'Parent', app.UIAxes_3);
        end

        % Value changed function: ORCheckBox
        function or(app, event)
            global image1
            global image2
            r = imresize(image2,size(image1));
            k = image1 | r ;
            imshow(image1, 'Parent', app.UIAxes);
            imshow(image2, 'Parent', app.UIAxes_2);
            imshow(k, 'Parent', app.UIAxes_3);
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LogicalOperationsonBinaryImagesLabel = uilabel(app.UIFigure);
            app.LogicalOperationsonBinaryImagesLabel.Text = 'Logical Operations on Binary Images';
            app.ORCheckBox = uicheckbox(app.UIFigure);
            app.ORCheckBox.ValueChangedFcn = createCallbackFcn(app, @or, true);
            app.ORCheckBox.Text = 'OR';
            app.XORCheckBox = uicheckbox(app.UIFigure);
            app.XORCheckBox.ValueChangedFcn = createCallbackFcn(app, @oor, true);
            app.XORCheckBox.Text = 'XOR';
            app.NOTCheckBox = uicheckbox(app.UIFigure);
            app.NOTCheckBox.ValueChangedFcn = createCallbackFcn(app, @not, true);
            app.NOTCheckBox.Text = 'NOT';
            app.ANDCheckBox = uicheckbox(app.UIFigure);
            app.ANDCheckBox.ValueChangedFcn = createCallbackFcn(app, @AND, true);
            app.ANDCheckBox.Text = 'AND';
            app.BrowseImage1Button = uibutton(app.UIFigure, 'push');
            app.BrowseImage1Button.ButtonPushedFcn = createCallbackFcn(app, @image1, true);
            app.BrowseImage1Button.Text = 'Browse Image 1';
            app.BrowseImage2Button = uibutton(app.UIFigure, 'push');
            app.BrowseImage2Button.ButtonPushedFcn = createCallbackFcn(app, @image2, true);
            app.BrowseImage2Button.Text = 'Browse Image 2';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image 1')
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Image 1')
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Image 1')
            app.UIAxes_3.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = logic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end